classdef DualLickGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RCalibrateEditField             matlab.ui.control.Spinner
        RightSpinnerLabel               matlab.ui.control.Label
        LCalibrateEditField             matlab.ui.control.Spinner
        LeftSpinnerLabel                matlab.ui.control.Label
        RunTimeLabel                    matlab.ui.control.Label
        PulseModulationParametersPanel  matlab.ui.container.Panel
        OptoDurationmsEditField         matlab.ui.control.NumericEditField
        OptoDurationmsEditFieldLabel    matlab.ui.control.Label
        OptoFrequencyHzEditField        matlab.ui.control.NumericEditField
        OptoFrequencyHzEditFieldLabel   matlab.ui.control.Label
        PulseDurationmsEditField        matlab.ui.control.NumericEditField
        PulseDurationmsEditFieldLabel   matlab.ui.control.Label
        ContinuousCheckBox              matlab.ui.control.CheckBox
        MultiStimParametersPanel        matlab.ui.container.Panel
        AllowmultistiminblockCheckBox   matlab.ui.control.CheckBox
        AllowmultistiminENLpenaltyCheckBox  matlab.ui.control.CheckBox
        CuePenaltyLabel                 matlab.ui.control.Label
        WhentoOptoStimulatePanel        matlab.ui.container.Panel
        ConsumptionCheckBox             matlab.ui.control.CheckBox
        CueCheckBox                     matlab.ui.control.CheckBox
        ENLCheckBox                     matlab.ui.control.CheckBox
        BlockSwitchCheckBox             matlab.ui.control.CheckBox
        OptoStimulationSwitch           matlab.ui.control.Switch
        OptoLabel                       matlab.ui.control.Label
        OptoDuringLabel                 matlab.ui.control.Label
        SpoutStartDropDownLabel         matlab.ui.control.Label
        HowtoOptoStimulatePanel         matlab.ui.container.Panel
        OptoProbabilitySpinner          matlab.ui.control.Spinner
        OptoProbabilitySpinnerLabel     matlab.ui.control.Label
        OptoDelaymsEditField            matlab.ui.control.NumericEditField
        OptoDelaymsEditFieldLabel       matlab.ui.control.Label
        CorrecttrialsbeforeoptoEditField  matlab.ui.control.NumericEditField
        CorrecttrialsbeforeoptoEditFieldLabel  matlab.ui.control.Label
        CueFreqhzLabel                  matlab.ui.control.Label
        RightCuefreqEditField           matlab.ui.control.NumericEditField
        LeftCuefreqEditField            matlab.ui.control.NumericEditField
        CuePenaltymsEditField           matlab.ui.control.NumericEditField
        SpoutStart                      matlab.ui.control.DropDown
        RewardProbabilitySpinner        matlab.ui.control.Spinner
        RewardProbabilitySpinnerLabel   matlab.ui.control.Label
        RCalibrateButton                matlab.ui.control.StateButton
        LCalibrateButton                matlab.ui.control.StateButton
        RightStepEditField              matlab.ui.control.NumericEditField
        StepEditField_2Label_2          matlab.ui.control.Label
        MinEditField_2Label_4           matlab.ui.control.Label
        RightMaxEditField               matlab.ui.control.NumericEditField
        MaxEditField_2Label_4           matlab.ui.control.Label
        RightMinEditField               matlab.ui.control.NumericEditField
        RightrewardsizeLabel            matlab.ui.control.Label
        LeftStepEditField               matlab.ui.control.NumericEditField
        StepEditField_2Label            matlab.ui.control.Label
        MinEditField_2Label_3           matlab.ui.control.Label
        LeftMaxEditField                matlab.ui.control.NumericEditField
        MaxEditField_2Label_3           matlab.ui.control.Label
        LeftMinEditField                matlab.ui.control.NumericEditField
        LeftrewardsizeLabel             matlab.ui.control.Label
        ENLStepEditField                matlab.ui.control.NumericEditField
        MaxEditField_2Label_2           matlab.ui.control.Label
        ENLMinEditField                 matlab.ui.control.NumericEditField
        MinEditField_2Label_2           matlab.ui.control.Label
        ENLMaxEditField                 matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        ENLdurationmsLabel              matlab.ui.control.Label
        ParametersLabel                 matlab.ui.control.Label
        ExperimentalDataLabel           matlab.ui.control.Label
        ResaveButton                    matlab.ui.control.Button
        OmissionsEditField              matlab.ui.control.NumericEditField
        MaxOmissionsLabel               matlab.ui.control.Label
        RightLabel_2                    matlab.ui.control.Label
        LeftLabel_2                     matlab.ui.control.Label
        TimeoutEditField                matlab.ui.control.NumericEditField
        TimeoutmLabel                   matlab.ui.control.Label
        TrialInBlockLabel               matlab.ui.control.Label
        Seven                           matlab.ui.control.Label
        FreeRewLabel                    matlab.ui.control.Label
        Six                             matlab.ui.control.Label
        RightLicksLabel                 matlab.ui.control.Label
        LeftLicksLabel                  matlab.ui.control.Label
        IdleRight                       matlab.ui.control.Label
        IdleLeft                        matlab.ui.control.Label
        BlockLabel                      matlab.ui.control.Label
        BlockEventLabel                 matlab.ui.control.Label
        TimeLabel                       matlab.ui.control.Label
        OutOfLabel                      matlab.ui.control.Label
        TotRewLabel                     matlab.ui.control.Label
        NumBlockLabel                   matlab.ui.control.Label
        RewBlockLabel                   matlab.ui.control.Label
        NumTrialsLabel                  matlab.ui.control.Label
        RLCountLabel                    matlab.ui.control.Label
        LLCountLabel                    matlab.ui.control.Label
        Five                            matlab.ui.control.Label
        Two                             matlab.ui.control.Label
        Three                           matlab.ui.control.Label
        Four                            matlab.ui.control.Label
        LResetButton                    matlab.ui.control.Button
        RResetButton                    matlab.ui.control.Button
        StopExperimentButton            matlab.ui.control.Button
        StartExperimentButton           matlab.ui.control.Button
        EventLabel                      matlab.ui.control.Label
        EventLabel_2                    matlab.ui.control.Label
        RightLabel                      matlab.ui.control.Label
        LeftLabel                       matlab.ui.control.Label
        WaterCueSwitch                  matlab.ui.control.Switch
        WaterCueSwitchLabel             matlab.ui.control.Label
        ManualTriggeringLabel           matlab.ui.control.Label
        RCueButton                      matlab.ui.control.Button
        LCueButton                      matlab.ui.control.Button
        TrialsperblockLabel             matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MinEditField_2Label             matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        RWaterButton                    matlab.ui.control.Button
        LWaterButton                    matlab.ui.control.Button
        NameEditField                   matlab.ui.control.EditField
        NameLabel                       matlab.ui.control.Label
        EndBlockButton                  matlab.ui.control.StateButton
        EndTrialButton                  matlab.ui.control.StateButton
        SelectTimeEditField             matlab.ui.control.NumericEditField
        SelectiontimemsLabel            matlab.ui.control.Label
        Fails2RewEditField              matlab.ui.control.NumericEditField
        FailsuntilrewardsLabel          matlab.ui.control.Label
        One                             matlab.ui.control.Label
        Graph                           matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        WillstopexperimentbutwillnotsavedataMenu  matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        WillstopexperimentattheendofthecurrentblockMenu  matlab.ui.container.Menu
        ContextMenu3                    matlab.ui.container.ContextMenu
        WillstopexperimentattheendofthecurrenttrialMenu  matlab.ui.container.Menu
        ContextMenu4                    matlab.ui.container.ContextMenu
        StartexperimentMenu             matlab.ui.container.Menu
        ContextMenu5                    matlab.ui.container.ContextMenu
        MinimumnumberoftrialsineachblockMenu  matlab.ui.container.Menu
        ContextMenu6                    matlab.ui.container.ContextMenu
        MaximumnumberoftrialsperblockMenu  matlab.ui.container.Menu
        ContextMenu7                    matlab.ui.container.ContextMenu
        Menu2                           matlab.ui.container.Menu
        ContextMenu8                    matlab.ui.container.ContextMenu
        AmountoftimeanimalhastomakeaselectionbeforeanomissionMenu  matlab.ui.container.Menu
        ContextMenu9                    matlab.ui.container.ContextMenu
        Menu2_2                         matlab.ui.container.Menu
        ContextMenu10                   matlab.ui.container.ContextMenu
        MinimumENLdurationMenu          matlab.ui.container.Menu
        ContextMenu11                   matlab.ui.container.ContextMenu
        MaximumENLdurationMenu          matlab.ui.container.Menu
        ContextMenu12                   matlab.ui.container.ContextMenu
        MinimumleftrewardsizedurationsolenoidsareopenforMenu  matlab.ui.container.Menu
        ContextMenu13                   matlab.ui.container.ContextMenu
        MaximumleftrewardsizedurationsolenoidsareopenforMenu  matlab.ui.container.Menu
        ContextMenu14                   matlab.ui.container.ContextMenu
        MinimumrightrewardsizedurationsolenoidsareopenforMenu  matlab.ui.container.Menu
        ContextMenu15                   matlab.ui.container.ContextMenu
        MaximumrightrewardsizedurationsolenoidsareopenforMenu  matlab.ui.container.Menu
        ContextMenu16                   matlab.ui.container.ContextMenu
        ProbabilitythatarewardisgivenintheeventofacorrectlickMenu  matlab.ui.container.Menu
        ContextMenu17                   matlab.ui.container.ContextMenu
        WhichblocktheexperimentbeginsonMenu  matlab.ui.container.Menu
        ContextMenu18                   matlab.ui.container.ContextMenu
        NameoftheanimalUsedtonamethebehavioraldatafilesMenu  matlab.ui.container.Menu
        ContextMenu19                   matlab.ui.container.ContextMenu
        ExperimentrunningtimerMenu      matlab.ui.container.Menu
        ContextMenu20                   matlab.ui.container.ContextMenu
        ResavebehavioraldatacollectedMenu  matlab.ui.container.Menu
        ContextMenu21                   matlab.ui.container.ContextMenu
        Menu2_3                         matlab.ui.container.Menu
        ContextMenu22                   matlab.ui.container.ContextMenu
        Menu2_4                         matlab.ui.container.Menu
        ContextMenu23                   matlab.ui.container.ContextMenu
        Menu2_5                         matlab.ui.container.Menu
        ContextMenu24                   matlab.ui.container.ContextMenu
        WhateventtheexperimentiscurrentlyonMenu  matlab.ui.container.Menu
        ContextMenu25                   matlab.ui.container.ContextMenu
        WhichblocksidetheexperimentiscurrentlyonMenu  matlab.ui.container.Menu
        ContextMenu26                   matlab.ui.container.ContextMenu
        FrequencyofleftcueMenu          matlab.ui.container.Menu
        ContextMenu27                   matlab.ui.container.ContextMenu
        FrequencyofrightcueMenu         matlab.ui.container.Menu
        ContextMenu28                   matlab.ui.container.ContextMenu
        ManualleftcueMenu               matlab.ui.container.Menu
        ContextMenu29                   matlab.ui.container.ContextMenu
        ManualrightcueMenu              matlab.ui.container.Menu
        ContextMenu30                   matlab.ui.container.ContextMenu
        ManualleftrewardMenu            matlab.ui.container.Menu
        ContextMenu31                   matlab.ui.container.ContextMenu
        ManualrightrewardMenu           matlab.ui.container.Menu
        ContextMenu32                   matlab.ui.container.ContextMenu
        WhenoffwaterandcuewilloperateindependentlyMenu  matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        ContextMenu33                   matlab.ui.container.ContextMenu
        Resetnumberofmanualleftrewardsto0Menu  matlab.ui.container.Menu
        ContextMenu34                   matlab.ui.container.ContextMenu
        Resetnumberofmanualrightrewardsto0Menu  matlab.ui.container.Menu
        ContextMenu35                   matlab.ui.container.ContextMenu
        NumberofmanualleftrewardsMenu   matlab.ui.container.Menu
        ContextMenu36                   matlab.ui.container.ContextMenu
        Resetnumberofmanualrightrewardsto0Menu_2  matlab.ui.container.Menu
        ContextMenu37                   matlab.ui.container.ContextMenu
        Willcause100leftrewardsUsedtocalibrateleftrewardsizeMenu  matlab.ui.container.Menu
        ContextMenu38                   matlab.ui.container.ContextMenu
        Willcause100rightrewardsUsedtocalibraterightrewardsizeMenu  matlab.ui.container.Menu
        ContextMenu39                   matlab.ui.container.ContextMenu
        NumberofmanualrightrewardsMenu  matlab.ui.container.Menu
        ContextMenu40                   matlab.ui.container.ContextMenu
        Menu2_6                         matlab.ui.container.Menu
        ContextMenu41                   matlab.ui.container.ContextMenu
        ProbabilitythatoptostimulationwilloccurMenu  matlab.ui.container.Menu
        ContextMenu42                   matlab.ui.container.ContextMenu
        NumberofcorrecttrialsnecessarybeforeoptobeginsMenu  matlab.ui.container.Menu
        ContextMenu43                   matlab.ui.container.ContextMenu
        TimebetweenoptostarttimeandwhenthelaserisactuallyonMenu  matlab.ui.container.Menu
        ContextMenu44                   matlab.ui.container.ContextMenu
        IfoptoactiveduringENLallowoptostimeverytimeENLisresetMenu  matlab.ui.container.Menu
        ContextMenu45                   matlab.ui.container.ContextMenu
        AllowmultipleoptostimulationsinablockMenu  matlab.ui.container.Menu
        ContextMenu46                   matlab.ui.container.ContextMenu
        AllowoptogeneticstimulationtohavecontinuouslaserstimulationMenu  matlab.ui.container.Menu
        ContextMenu47                   matlab.ui.container.ContextMenu
        DurationthatlaserisonforMenu    matlab.ui.container.Menu
        ContextMenu48                   matlab.ui.container.ContextMenu
        IfnoncontinuousthedurationofeachlaserpulseMenu  matlab.ui.container.Menu
        ContextMenu49                   matlab.ui.container.ContextMenu
        IfnoncontinuousfrequencyoflaserMenu  matlab.ui.container.Menu
        ContextMenu50                   matlab.ui.container.ContextMenu
        NumberofmanualrightrewardsMenu_2  matlab.ui.container.Menu
        ContextMenu51                   matlab.ui.container.ContextMenu
        LeftrewardsizedurationsolenoidsareopenforMenu  matlab.ui.container.Menu
        ContextMenu52                   matlab.ui.container.ContextMenu
        RightrewardsizedurationsolenoidsareopenforMenu  matlab.ui.container.Menu
    end

    %% Properties
    properties (Access = private)

        % Objects
        tee % teensy object. created at startup using AppArduinoConnection
        
        % Flags
        isRunning = false; % experiment is running?
        messageReceived = false; % handshake
        timed = false; % timeout has been reached
        omitted = false; % max omissions has been reached
        stopped = false; % emergency stop has been clicked
        badData = false; % emergency save data
        
        % Graphing
        selection = 0; % selection time
        lick = 0; % lick time
        blockSwitch % block   (true = left, false = right)
        visibleTrials = 100; % number of maximum trials shown at one time

        % Counters
        LRCount = 0; % counter for left rewards
        RRCount = 0; % counter for right rewards
        idleLeftCount = 0; % counter for left licks
        idleRightCount = 0; % counter for right licks
        omissionCount = 0; % counter for omissions
        
        % Behavior data
        eventsData = {}; % all events
        blockData = {}; % all block summaries
        settingsData = {}; % all settings
        syncData = {}; % all sync pulses
        currentEvent = 1; % Index for eventsData
        currentSync = 1; % Index for syncData
        currentBlock = 1; % Index for blockData
        
        % Other
        timeout = 60; % max number of minutes before ending
        maxOmissions = 20; % max number of consecutive omissions before ending
        mouseName % animal's name
        Timer % timer object of class timer

    end
    
    %% Public Methods
    methods (Access = public)
        
        % Default handler
        function defaultHandler(app, port, ~)

            msg = strtrim(readline(port));
            if ~isempty(msg)
                app.messageReceived = true;
            end

        end

    end

    %% Private Methods
    methods (Access = private)
        
        % End experiment
        function endExperiment(app)
            
            % Send stop message
            app.tee.writeString('9');
            app.EventLabel.Text = 'Experiment Ended';

        end

        % Save all settings, events, block data, and sync pulses
        function saveData(app)

            % Reset buttons
            app.EndTrialButton.Value = false;
            app.EndBlockButton.Value = false;

            % Force task data
            if (isempty(app.eventsData) || isempty(app.blockData)) && ~app.badData
                myMsgbox('No data to save', 'ERROR', 'error');
                return;
            end

            % Force animal name
            if isempty(app.mouseName)
                myMsgbox('No animal name, cannot save data. Add name and re-save', 'ERROR', 'error');
                return;
            end

            % Set directory to desktop folder
            desktopPath = fullfile(getenv('USERPROFILE'), 'Desktop');
            directory = fullfile(desktopPath, 'Behavior_Data');
            % make folder if it doesn't exist
            if ~exist(directory, 'dir')
                mkdir(directory);
            end
            cd(directory);

            % Create unique folder name and set new directory
            currentDate = char(datetime('now', 'Format', 'yyyyMMdd'));
            baseFolderName = [currentDate, '_', app.mouseName];
            folderName = baseFolderName;
            folderPath = fullfile(directory, folderName);
            runCount = 2;
            while exist(folderPath, 'dir')
                folderName = [baseFolderName, '_run', num2str(runCount)];
                folderPath = fullfile(directory, folderName);
                runCount = runCount + 1;
            end
            mkdir(folderPath);
            cd(folderPath);

            % Choose settings to save and corresponding labels
            theSettings = [app.NameEditField, app.MinEditField, app.MaxEditField, app.SelectTimeEditField, app.CuePenaltymsEditField, app.Fails2RewEditField, app.ENLMinEditField, app.ENLMaxEditField, ... 
                app.ENLStepEditField, app.LeftMinEditField, app.LeftMaxEditField, app.LeftStepEditField, app.RightMinEditField, app.RightMaxEditField, app.RightStepEditField, ... 
                app.RewardProbabilitySpinner, app.SpoutStart, app.LeftCuefreqEditField, app.RightCuefreqEditField, app.OptoStimulationSwitch, app.BlockSwitchCheckBox, ... 
                app.ConsumptionCheckBox, app.ENLCheckBox, app.CueCheckBox, app.OptoProbabilitySpinner, app.CorrecttrialsbeforeoptoEditField, app.OptoDelaymsEditField, ...
                app.AllowmultistiminENLpenaltyCheckBox, app.AllowmultistiminblockCheckBox, app.ContinuousCheckBox, app.PulseDurationmsEditField, app.OptoFrequencyHzEditField, ...
                app.OptoDurationmsEditField, app.OmissionsEditField, app.TimeoutEditField];
            theLabels = ["AnimalName", "MinBlock", "MaxBlock", "SelectionTime(ms)", "CuePenalty(ms)", "FailsUntilReward", "MinENL(ms)", "MaxENL(ms)", "ENLStep", "MinLeft(ms)", "MaxLeft(ms)", "LeftStep", ... 
                "MinRight(ms)", "MaxRight(ms)", "RightStep", "RewardProbability", "SpoutStart", "LeftCueFreq(hz)", "RightCueFreq(hz)", "Opto", "OptoDuringBlockSwitch", "OptoDuringConsumption", ...
                "OptoDuringENL", "OptoDuringCue", "OptoProbability", "CorrectTrialsBeforeOpto", "OptoDelay(ms)", "AllowMultiStimENL", "AllowMultiStimBlock", "ContinuousOpto", "PulseDuration(ms)", ...
                "OptoFrequency(Hz)", "OptoDuration(ms)", "MaxOmissions", "Timeout(min)"];

            % Save all settings
            for i = 1:length(theSettings)
                currentValue = theSettings(i).Value;
        
                if ~isnumeric(currentValue)
                    currentValue = string(currentValue);
                end
        
                app.settingsData{end+1, 1} = theLabels(i);
                app.settingsData{end, 2} = currentValue;
            end
            app.settingsData{end+1, 1} = 'DataSavedTo';
            app.settingsData{end, 2} = [folderName, ' in ', directory];
            
            % Find Arduino file
            appFilePath = mfilename('fullpath');
            [appFolder, appFileName, ~] = fileparts(appFilePath);
            inoFile = dir(fullfile(appFolder, '**', '*.ino'));

            % Save Arduino file name
            if isempty(inoFile)
                myMsgbox(['Arduino code must be stored in the same folder as ', appFileName], 'ERROR', 'error');
                app.settingsData{end+1, 1} = 'Arduino Code';
                app.settingsData{end, 2} = 'Not Found';
            elseif length(inoFile) > 1
                myMsgbox('Only 1 arduino file can be stored in this folder at a time', 'ERROR', 'error');
                app.settingsData{end+1, 1} = 'Arduino Code';
                app.settingsData{end, 2} = 'Not Found';
            else
                inoFileName = inoFile(1).name;
                app.settingsData{end+1, 1} = 'Arduino Code';
                app.settingsData{end, 2} = inoFileName;
            end

            % Save MATLAB file name
            app.settingsData{end+1, 1} = 'MATLAB Code';
            app.settingsData{end, 2} = appFileName;

            % Delete unnecessary rows of data
            if ~app.badData
                app.eventsData(end-1, :) = [];
                app.eventsData(end-1, :) = [];
            end

            % Make all times relative to experiment start time
            startTime = app.eventsData{2, 6};
            for i = 2:size(app.eventsData, 1)
                app.eventsData{i, 6} = app.eventsData{i, 6} - startTime;
            end
            for i = 2:size(app.syncData, 1)
                app.syncData{i, 2} = app.syncData{i, 2} - startTime;
            end

            % Make file names
            eventsDataFile = [currentDate, '_', app.mouseName, '_Events.csv'];
            if app.badData
                settingsDataFile = [currentDate, '_', app.mouseName, '_BadSettings.csv'];
                blocksDataFile = [currentDate, '_', app.mouseName, '_BadBlocks.csv'];
            else
                settingsDataFile = [currentDate, '_', app.mouseName, '_Settings.csv'];
                blocksDataFile = [currentDate, '_', app.mouseName, '_Blocks.csv'];
            end
            syncDataFile = [currentDate, '_', app.mouseName, '_SyncPulses.csv'];
            
            % Save all data
            events = app.eventsData;
            settings = app.settingsData;
            block = app.blockData;
            sync = app.syncData;
            writecell(events, eventsDataFile);
            writecell(settings, settingsDataFile);
            writecell(block, blocksDataFile);
            writecell(sync, syncDataFile);
            if app.badData
                myMsgbox(['Some bad files saved to ', folderName], 'Message', 'error');
            else
                myMsgbox(['All files saved to ', folderName], 'Message', 'none');
            end

        end

        function updateTimer(app, startTime)

            % Check if the timer should still be running
            if ~app.isRunning
                % Stop and delete timer
                stop(app.Timer);
                delete(app.Timer);
                app.Timer = [];

                % Return app to idle state
                hold(app.Graph, 'off');
                app.StartExperimentButton.Enable = 'on';
                app.tee.setMessageHandler(@app.setupHandler);
                return;
            end

            % Compute elapsed time
            elapsedTime = toc(startTime);
            hours = floor(elapsedTime / 3600);
            minutes = floor(mod(elapsedTime, 3600) / 60);
            seconds = mod(elapsedTime, 60);

            % Format time label
            if hours > 0
                timeDisplay = sprintf('%02d:%02d:%02d', hours, minutes, floor(seconds));
            else
                timeDisplay = sprintf('%02d:%02d', minutes, floor(seconds));
            end
            app.TimeLabel.Text = timeDisplay;

            % End timer if timeout is reached
            if minutes >= app.timeout || hours >= app.timeout / 60
                app.timed = true;
            end

        end

        %% Message handlers

        % Setup handler
        function setupHandler(app, port, ~)

            msg = strtrim(readline(port));

            % Make variables visible to user
            app.TimeoutEditField.Value = app.timeout;
            app.OmissionsEditField.Value = app.maxOmissions;

            % Parse messages
            if ~isempty(msg)
                parts = strsplit(msg, '\t');
                
                % Handle lick events
                if length(parts) >= 5
                    switch parts{5} % {5} is the event
                        case 'LickRight'
                            app.idleRightCount = app.idleRightCount + 1;
                            app.IdleRight.Text = num2str(app.idleRightCount);
                        case 'LickLeft'
                            app.idleLeftCount = app.idleLeftCount + 1;
                            app.IdleLeft.Text = num2str(app.idleLeftCount);
                    end

                % Handle settings messages
                elseif length(parts) == 2
                    parameter = str2double(parts{2});

                    % Catch default EEPROM values
                    if parameter == 255 || parameter == 4294967295
                        parameter = 0;
                    end

                    switch parts{1}
                        case 'Min Block'
                            app.MinEditField.Value = parameter;
                        case 'Max Block'
                            app.MaxEditField.Value = parameter;
                        case 'Fails until reward'
                            app.Fails2RewEditField.Value = parameter;
                        case 'Reaction Time'
                            app.SelectTimeEditField.Value = parameter;
                        case 'Cue Penalty'
                            app.CuePenaltymsEditField.Value = parameter;
                        case 'Left Calibration'
                            app.LCalibrateEditField.Value = parameter;
                        case 'Right Calibration'
                            app.RCalibrateEditField.Value = parameter;
                        case 'Min ENL'
                            app.ENLMinEditField.Value = parameter;
                        case 'Max ENL'
                            app.ENLMaxEditField.Value = parameter;
                        case 'ENL Step'
                            app.ENLStepEditField.Value = parameter;
                        case 'Min Left'
                            app.LeftMinEditField.Value = parameter;
                        case 'Max Left'
                            app.LeftMaxEditField.Value = parameter;
                        case 'Left Step'
                            app.LeftStepEditField.Value = parameter;
                        case 'Min Right'
                            app.RightMinEditField.Value = parameter;
                        case 'Max Right'
                            app.RightMaxEditField.Value = parameter;
                        case 'Right Step'
                            app.RightStepEditField.Value = parameter;
                        case 'Reward Prob'
                            app.RewardProbabilitySpinner.Value = parameter;
                        case 'Spout Start'
                            if parameter == 0
                                app.SpoutStart.Value = 'Random';
                            elseif parameter == 1
                                app.SpoutStart.Value = 'Left';
                            elseif parameter == 2
                                app.SpoutStart.Value = 'Right';
                            end
                        case 'Left Cue Frequency'
                            app.LeftCuefreqEditField.Value = parameter;
                        case 'Right Cue Frequency'
                            app.RightCuefreqEditField.Value = parameter;
                        case 'Opto on'
                            if parameter == 1
                                app.OptoStimulationSwitch.Value = 'on';
                            else
                                app.OptoStimulationSwitch.Value = 'off';
                            end
                        case 'Continuous'
                            app.ContinuousCheckBox.Value = setCheck(parameter);
                        case 'Opto Probability'
                            app.OptoProbabilitySpinner.Value = parameter;
                        case 'Correct Trials Before Opto'
                            app.CorrecttrialsbeforeoptoEditField.Value = parameter;
                        case 'Opto Delay'
                            app.OptoDelaymsEditField.Value = parameter;
                        case 'Opto Duration'
                            app.OptoDurationmsEditField.Value = parameter;
                        case 'Pulse Duration'
                            app.PulseDurationmsEditField.Value = parameter;
                        case 'Laser Frequency'
                            app.OptoFrequencyHzEditField.Value = parameter;
                        case 'Opto In Block Switch'
                            app.BlockSwitchCheckBox.Value = setCheck(parameter);
                        case 'Opto In ENL'
                            app.ENLCheckBox.Value = setCheck(parameter);
                        case 'Opto In Cue'
                            app.CueCheckBox.Value = setCheck(parameter);
                        case 'Opto In Consumption'
                            app.ConsumptionCheckBox.Value = setCheck(parameter);
                        case 'Multi-stim In ENL'
                            app.AllowmultistiminENLpenaltyCheckBox.Value = setCheck(parameter);
                        case 'Multi-stim In Block'
                            app.AllowmultistiminblockCheckBox.Value = setCheck(parameter);
                    end
                end
            end
        end

        % Data handler
        function dataHandler(app, port, ~)

            % Parse messages
            msg = strtrim(readline(port));
            parts = cellstr(strsplit(msg, '\t'));

            % Convert numeric values
            for i = 1:length(parts)
                if ~isnan(str2double(parts{i}))
                    parts{i} = str2double(parts{i});
                end
            end
            
            % Save Block data
            if length(parts) > 8
                if strcmp(parts{1}, 'Block Num')
                    tmp = [];
                    for i = 1:length(parts)
                        if mod(i, 2) == 0
                            tmp{end+1} = parts{i};
                        end
                    end
                    app.currentBlock = app.currentBlock + 1;
                    app.blockData(app.currentBlock, :) = tmp;
                end
            
            % Save sync pulse data
            elseif length(parts) == 3
                if isempty(app.syncData)
                    app.syncData = {'status', 'time', 'duration'};
                end
                app.currentSync = app.currentSync + 1;
                app.syncData(app.currentSync, :) = parts;
            
            % Handle most data
            elseif length(parts) == 7

                % Save event data
                if isempty(app.eventsData)
                    app.eventsData = {'nTrial', 'iBlock', 'iTrial', 'iRewardCount', 'event', 'eventTime', 'duration'};
                end
                app.currentEvent = app.currentEvent + 1;
                app.eventsData(app.currentEvent, :) = parts;

                % Update graph frame
                currentTrial = parts{1};
                if currentTrial > app.visibleTrials
                    app.Graph.XLim = [currentTrial - app.visibleTrials + 1, currentTrial + 1];
                else
                    app.Graph.XLim = [0, currentTrial + 1];
                end

                % Handle Events
                switch parts{5}
                    case {'ENL_RIGHT', 'ENL_LEFT'}
    
                        % Check if experiment ends on this trial
                        if app.timed
                            app.endExperiment();
                            app.settingsData{end+1, 1} = 'Stopped by';
                            app.settingsData{end, 2} = 'Timeout';
                            app.timed = false;
                        elseif app.omitted
                            app.endExperiment();
                            app.settingsData{end+1, 1} = 'Stopped by';
                            app.settingsData{end, 2} = 'Omissions';
                            app.omitted = false;
                        elseif app.EndTrialButton.Value
                            app.endExperiment();
                            if ~app.EndBlockButton.Value
                                app.settingsData{end+1, 1} = 'Stopped by';
                                app.settingsData{end, 2} = 'End of Trial';
                            end
                        else
                            app.EventLabel.Text = 'ENL';
                            if strcmp(parts{5}, 'ENL_LEFT')
                                app.blockSwitch = true;
                                app.BlockLabel.Text = 'Left';
                            elseif strcmp(parts{5}, 'ENL_RIGHT')
                                app.blockSwitch = false;
                                app.BlockLabel.Text = 'Right';
                            end
                            app.NumTrialsLabel.Text = num2str(parts{1});
                            app.NumBlockLabel.Text = num2str(parts{2});
                            app.TrialInBlockLabel.Text = num2str(parts{3});
                            
                            % Plot trial placeholder
                            if app.blockSwitch
                                scatter(app.Graph, parts{1}, 1, 15, 'MarkerEdgeColor', [0.5 0.5 0.5]);
                            else
                                scatter(app.Graph, parts{1}, 2, 15, 'MarkerEdgeColor', [0.5 0.5 0.5]);
                            end
                        end
                    case 'ENL_PENALTY'
                        app.EventLabel.Text = 'ENL - Penalty';
                    case 'SELECTION'
                        app.EventLabel.Text = 'Selection';
                        app.selection = parts{6};
                    case 'Laser START'
                        if ~app.timed && ~app.omitted && ~app.stopped ... 
                                && ~app.EndTrialButton.Value && ~app.EndBlockButton.Value
                            if app.blockSwitch
                                scatter(app.Graph, parts{1}, 1.2, 20, 'filled', 'MarkerFaceColor', '#edb120');
                            else
                                scatter(app.Graph, parts{1}, 1.8, 20, 'filled', 'MarkerFaceColor', '#edb120');
                            end
                        end
                        
                    % Plot licks
                    case '# CORRECT RIGHT'
                        app.lick = parts{6};
                        scatter(app.Graph, parts{1}, 2, getSize(app.lick, app.selection), 'filled', 'MarkerFaceColor', '#7bde18');
                        app.EventLabel.Text = 'Correct - Reward';
                        app.omissionCount = 0;
                    case '# CORRECT LEFT'
                        app.lick = parts{6};
                        scatter(app.Graph, parts{1}, 1, getSize(app.lick, app.selection), 'filled', 'MarkerFaceColor', '#7bde18');
                        app.EventLabel.Text = 'Correct - Reward';
                        app.omissionCount = 0;
                    case '# Incorrect LEFT'
                        app.lick = parts{6};
                        scatter(app.Graph, parts{1}, 2, getSize(app.lick, app.selection), 'filled', 'MarkerFaceColor', '#e61a33');
                        app.EventLabel.Text = 'Incorrect';
                        app.omissionCount = 0;
                    case '# Incorrect RIGHT'
                        app.lick = parts{6};
                        scatter(app.Graph, parts{1}, 1, getSize(app.lick, app.selection), 'filled', 'MarkerFaceColor', '#e61a33');
                        app.EventLabel.Text = 'Incorrect';
                        app.omissionCount = 0;
                    case 'NO_REWARD'
                        app.EventLabel.Text = 'Correct - No Reward';
                    case '# Timeout'
                        if app.blockSwitch
                            scatter(app.Graph, parts{1}, 1, 65, 'filled', 'MarkerFaceColor', '#4682b4');
                        else
                            scatter(app.Graph, parts{1}, 2, 65, 'filled', 'MarkerFaceColor', '#4682b4');
                        end
                        app.EventLabel.Text = 'Omission';
                        app.omissionCount = app.omissionCount + 1;
                        if app.omissionCount >= app.maxOmissions
                            app.omitted = true;
                            app.omissionCount = 0;
                        end
                    case 'FREE_REWARD'
                        app.EventLabel.Text = 'Free Reward';
                    case 'LickRight'
                        app.idleRightCount = app.idleRightCount + 1;
                        app.IdleRight.Text = num2str(app.idleRightCount);
                    case 'LickLeft'
                        app.idleLeftCount = app.idleLeftCount + 1;
                        app.IdleLeft.Text = num2str(app.idleLeftCount);

                    % Once teensy is idle, save all data
                    case 'IDLE'
                        if ~app.stopped
                            app.isRunning = false;
                            app.saveData(); 
                        end
                        app.stopped = false;
                end

            % Handle Block Events
            elseif length(parts) == 2 && ~isempty(parts)
                switch parts{1}
                    case 'Block Stats'

                        % Save block data headers
                        if isempty(app.blockData)
                            app.blockData = {'nBlock', 'iSpout', 'blockLength', 'nTrials', 'nCorrect', 'nIncorrect', 'nRewards', 'nNoRewards', 'nENLPenalties', 'nOmissions', 'nFreeRewards', ... 
                                'nLeftLicks', 'nRightLicks', 'nWinRepeat', 'nLoseSwitch', 'nWinSwitch', 'nLoseRepeat', 'nBadTrialsPostReward', 'nTrialsToSwitch'};
                        end

                        % Check if experiment ends on this block
                        if app.EndBlockButton.Value
                            app.EndTrialButton.Value = true;
                            app.endExperiment();
                            app.settingsData{end+1, 1} = 'Stopped by';
                            app.settingsData{end, 2} = 'End of Block';
                        elseif ~app.timed && ~app.omitted && ~app.stopped ... 
                                && ~app.EndTrialButton.Value && ~app.EndBlockButton.Value
                            app.RewBlockLabel.Text = '0';
                        end
                    
                    % Update Experimental Data
                    case 'TASK STARTED AT'
                        app.settingsData{end+1, 1} = parts{1};
                        app.settingsData{end, 2} = parts{2};
                    case 'TASK ENDED AT'
                        app.settingsData{end+1, 1} = parts{1};
                        app.settingsData{end, 2}  = parts{2};
                    case 'Out of'
                        if ~app.timed && ~app.omitted && ~app.stopped ... 
                                && ~app.EndTrialButton.Value && ~app.EndBlockButton.Value
                            app.OutOfLabel.Text = num2str(parts{2});
                        end
                    case 'Reward in block'
                        app.RewBlockLabel.Text = num2str(parts{2});
                    case 'Total Rewards'
                        app.TotRewLabel.Text = num2str(parts{2});
                    case 'Free Rewards'
                        app.FreeRewLabel.Text = num2str(parts{2});
                    case 'Min Left'
                        app.LeftMinEditField.Value = parts{2};
                    case 'Max Left'
                        app.LeftMaxEditField.Value = parts{2};
                    case 'Left Step'
                        app.LeftStepEditField.Value = parts{2};
                    case 'Min Right'
                        app.RightMinEditField.Value = parts{2};
                    case 'Max Right'
                        app.RightMaxEditField.Value = parts{2};
                    case 'Right Step'
                        app.RightStepEditField.Value = parts{2};
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Clearing
            clc;
            warning('off', 'all');
            app.UIFigure.Position = [785, 300, 1116, 736];
            
            % Graph things
            app.Graph.YTick = [];
            app.Graph.YLim = [0.5, 2.5];
            app.Graph.XLim = [0, app.visibleTrials];
            app.Graph.XLimMode = 'manual';

            % Hide opto panels upon startup (can still scroll to it)
            % app.UIFigure.Position = [785, 300, 1130, 550];

            % Make teensy object
            app.tee = AppArduinoConnection(app, @app.defaultHandler, 9600);

            % Hide until handshake
            app.UIFigure.Visible = 'off';
            app.tee.writeString('^');
            connected = false;
            while ~connected
                connected = app.messageReceived;
                pause(0.01);
            end
            app.UIFigure.Visible = 'on';

            % Put app in setup mode
            app.tee.setMessageHandler(@app.setupHandler);
            OptoStimulationSwitchValueChanged(app, []);
            ContinuousCheckBoxValueChanged(app, []);
            app.tee.writeString('z');
        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            % Clearing
            clc;
            cla(app.Graph);

            % Empty data arrays
            app.eventsData = {};
            app.settingsData = {};
            app.blockData = {};
            app.syncData = {};
            app.badData = false;
            app.currentEvent = 1;
            app.currentSync = 1;
            app.currentBlock = 1;
            app.RewBlockLabel.Text = '0';

            % Setting
            app.tee.setMessageHandler(@app.dataHandler);
            hold(app.Graph, 'on');
            app.StartExperimentButton.Enable = 'off';
            
            % Send start message
            app.tee.writeString('8');
            app.isRunning = true;
            app.EventLabel.Text = 'Experiment Started';

            % Configure timer
            app.Timer = timer;
            app.Timer.Period = 1;  % Update the timer every 1 second
            app.Timer.ExecutionMode = 'fixedRate';
            app.Timer.BusyMode = 'drop';

            % Start timer
            app.TimeLabel.Text = '0';
            startTime = tic;
            app.Timer.TimerFcn = @(~, ~) updateTimer(app, startTime);
            start(app.Timer);
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            % For emergency ends only, bad block and settings data will be saved
            app.settingsData{end+1, 1} = 'Stopped by';
            app.settingsData{end, 2} = 'Emergency Stop';

            % End experiment
            app.stopped = true;
            app.endExperiment();
            app.isRunning = false;

            % Save some bad data
            app.badData = true;
            app.saveData();

            % Return to idle state
            app.tee.setMessageHandler(@app.setupHandler);
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.MinEditField, 'a'));
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.MaxEditField, 'b'));
        end

        % Value changed function: Fails2RewEditField
        function Fails2RewEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.Fails2RewEditField, 'c'));
        end

        % Value changed function: SelectTimeEditField
        function SelectTimeEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.SelectTimeEditField, 'd'));
        end

        % Button pushed function: LCueButton
        function LCueButtonPushed(app, event)
            app.tee.writeString('e');
        end

        % Button pushed function: RCueButton
        function RCueButtonPushed(app, event)
            app.tee.writeString('f');
        end

        % Value changed function: LCalibrateEditField
        function LCalibrateEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.LCalibrateEditField, 'g'));
        end

        % Value changed function: RCalibrateEditField
        function RCalibrateEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.RCalibrateEditField, 'h'));
        end

        % Button pushed function: LWaterButton
        function LWaterButtonPushed(app, event)
            % Send left reward. If switch is on, send cue as well
            value = app.WaterCueSwitch.Value;
            switch value
                case 'on'
                    app.tee.writeString('1');
                    app.LRCount = app.LRCount + 1;
                    app.LLCountLabel.Text = num2str(app.LRCount);
                    app.tee.writeString('e');
                case 'off'
                    app.tee.writeString('1');
                    app.LRCount = app.LRCount + 1;
                    app.LLCountLabel.Text = num2str(app.LRCount);
            end
        end

        % Button pushed function: RWaterButton
        function RWaterButtonPushed(app, event)
            % Send right reward. If switch is on, send cue as well
            value = app.WaterCueSwitch.Value;
            switch value
                case 'on'
                    app.tee.writeString('2');
                    app.RRCount = app.RRCount + 1;
                    app.RLCountLabel.Text = num2str(app.RRCount);
                    app.tee.writeString('f');
                case 'off'
                    app.tee.writeString('2');
                    app.RRCount = app.RRCount + 1;
                    app.RLCountLabel.Text = num2str(app.RRCount);
            end
        end

        % Value changed function: LCalibrateButton
        function LCalibrateButtonValueChanged(app, event)
            buttonOn = app.LCalibrateButton.Value;

            for i = 1:100
                if buttonOn
                    app.tee.writeString('1');
                    pause((app.LCalibrateEditField.Value * 2)/1000); % keep off for as long as it was on
                    buttonOn = app.LCalibrateButton.Value;
                end
            end
            app.LCalibrateButton.Value = false;
        end

        % Value changed function: RCalibrateButton
        function RCalibrateButtonValueChanged(app, event)
            buttonOn = app.RCalibrateButton.Value;
            
            for i = 1:100
                if buttonOn
                    app.tee.writeString('2');
                    pause((app.RCalibrateEditField.Value * 2)/1000); % keep off for as long as it was on
                    buttonOn = app.RCalibrateButton.Value;
                end
            end
            app.RCalibrateButton.Value = false;
        end

        % Button pushed function: LResetButton
        function LResetButtonPushed(app, event)
            % Reset count and label to 0
            app.LRCount = 0;
            app.LLCountLabel.Text = num2str(app.LRCount);
        end

        % Button pushed function: RResetButton
        function RResetButtonPushed(app, event)
            % Reset count and label to 0
            app.RRCount = 0;
            app.RLCountLabel.Text = num2str(app.RRCount);
        end

        % Value changed function: ENLMinEditField
        function ENLMinEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.ENLMinEditField, 'i'));
        end

        % Value changed function: ENLMaxEditField
        function ENLMaxEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.ENLMaxEditField, 'j'));
        end

        % Value changed function: ENLStepEditField
        function ENLStepEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.ENLStepEditField, 'k'));
        end

        % Value changed function: OptoStimulationSwitch
        function OptoStimulationSwitchValueChanged(app, event)
            value = app.OptoStimulationSwitch.Value;
            switch value
                case 'on'
                    app.tee.writeString('l');
                    app.BlockSwitchCheckBox.Enable = 'on';
                    app.ENLCheckBox.Enable = 'on';
                    app.CueCheckBox.Enable = 'on';
                    app.ConsumptionCheckBox.Enable = 'on';
                case 'off'
                    app.tee.writeString('m');

                    % Turn off options if opto is off
                    app.BlockSwitchCheckBox.Value = false;
                    BlockSwitchCheckBoxValueChanged(app, []);
                    app.ENLCheckBox.Value = false;
                    ENLCheckBoxValueChanged(app, []);
                    app.CueCheckBox.Value = false;
                    CueCheckBoxValueChanged(app, []);
                    app.ConsumptionCheckBox.Value = false;
                    ConsumptionCheckBoxValueChanged(app, []);
                    app.BlockSwitchCheckBox.Enable = 'off';
                    app.ENLCheckBox.Enable = 'off';
                    app.CueCheckBox.Enable = 'off';
                    app.ConsumptionCheckBox.Enable = 'off';
            end
        end

        % Value changed function: SpoutStart
        function SpoutStartValueChanged(app, event)
            option = app.SpoutStart.Value;
            switch option
                case 'Random'
                    app.tee.writeString('o');
                case 'Left'
                    app.tee.writeString('p');
                case 'Right'
                    app.tee.writeString('q');
            end
        end

        % Value changed function: LeftMinEditField
        function LeftMinEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.LeftMinEditField, 'r'));
        end

        % Value changed function: LeftMaxEditField
        function LeftMaxEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.LeftMaxEditField, 's'));
        end

        % Value changed function: LeftStepEditField
        function LeftStepEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.LeftStepEditField, 't'));
        end

        % Value changed function: RightMinEditField
        function RightMinEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.RightMinEditField, 'u'));
        end

        % Value changed function: RightMaxEditField
        function RightMaxEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.RightMaxEditField, 'v'));
        end

        % Value changed function: RightStepEditField
        function RightStepEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.RightStepEditField, 'w'));
        end

        % Value changed function: RewardProbabilitySpinner
        function RewardProbabilitySpinnerValueChanged(app, event)
            app.tee.writeString(msg(app.RewardProbabilitySpinner, 'x'));
        end

        % Value changed function: ContinuousCheckBox
        function ContinuousCheckBoxValueChanged(app, event)
            app.tee.writeString(msg(app.ContinuousCheckBox, 'A'));
            value = app.ContinuousCheckBox.Value;

            if value
                app.PulseDurationmsEditField.Enable = 'off';
                app.OptoFrequencyHzEditField.Enable = 'off';
            else
                app.PulseDurationmsEditField.Enable = 'on';
                app.OptoFrequencyHzEditField.Enable = 'on';
            end
        end

        % Value changed function: OptoProbabilitySpinner
        function OptoProbabilitySpinnerValueChanged(app, event)
            app.tee.writeString(msg(app.OptoProbabilitySpinner, 'B'));
        end

        % Value changed function: CorrecttrialsbeforeoptoEditField
        function CorrecttrialsbeforeoptoEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.CorrecttrialsbeforeoptoEditField, 'C'));
        end

        % Value changed function: OptoDelaymsEditField
        function OptoDelaymsEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.OptoDelaymsEditField, 'D'));
        end

        % Value changed function: OptoDurationmsEditField
        function OptoDurationmsEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.OptoDurationmsEditField, 'E'));
        end

        % Value changed function: PulseDurationmsEditField
        function PulseDurationmsEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.PulseDurationmsEditField, 'F'));
        end

        % Value changed function: OptoFrequencyHzEditField
        function OptoFrequencyHzEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.OptoFrequencyHzEditField, 'G'));
        end

        % Value changed function: BlockSwitchCheckBox
        function BlockSwitchCheckBoxValueChanged(app, event)
            app.tee.writeString(msg(app.BlockSwitchCheckBox, 'H'));
        end

        % Value changed function: ENLCheckBox
        function ENLCheckBoxValueChanged(app, event)
            app.tee.writeString(msg(app.ENLCheckBox, 'I'));
        end

        % Value changed function: CueCheckBox
        function CueCheckBoxValueChanged(app, event)
            app.tee.writeString(msg(app.CueCheckBox, 'J'));
        end

        % Value changed function: ConsumptionCheckBox
        function ConsumptionCheckBoxValueChanged(app, event)
            app.tee.writeString(msg(app.ConsumptionCheckBox, 'K'));
        end

        % Value changed function: AllowmultistiminENLpenaltyCheckBox
        function AllowmultistiminENLpenaltyCheckBoxValueChanged(app, event)
            app.tee.writeString(msg(app.AllowmultistiminENLpenaltyCheckBox, 'L'));
        end

        % Value changed function: AllowmultistiminblockCheckBox
        function AllowmultistiminblockCheckBoxValueChanged(app, event)
            app.tee.writeString(msg(app.AllowmultistiminblockCheckBox, 'M'));
        end

        % Value changed function: CuePenaltymsEditField
        function CuePenaltymsEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.CuePenaltymsEditField, 'N'));
        end

        % Value changed function: LeftCuefreqEditField
        function LeftCuefreqEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.LeftCuefreqEditField, 'O'));
        end

        % Value changed function: RightCuefreqEditField
        function RightCuefreqEditFieldValueChanged(app, event)
            app.tee.writeString(msg(app.RightCuefreqEditField, 'P'));
        end

        % Value changed function: TimeoutEditField
        function TimeoutEditFieldValueChanged(app, event)
            value = app.TimeoutEditField.Value;
            app.timeout = value;
        end

        % Value changed function: OmissionsEditField
        function OmissionsEditFieldValueChanged(app, event)
            value = app.OmissionsEditField.Value;
            app.maxOmissions = value;
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            value = app.NameEditField.Value;
            app.mouseName = value;
        end

        % Button pushed function: ResaveButton
        function ResaveButtonPushed(app, event)
            if length(app.settingsData) > 4
                for i = length(app.settingsData):-1:4
                    app.settingsData(i, :) = [];
                end
            end
            app.saveData();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Save all parameters to the EEPROM
            app.tee.writeString('y'); % to preserve memory, keep commented until needed
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Graph = uiaxes(app.UIFigure);
            xlabel(app.Graph, 'Trial')
            zlabel(app.Graph, 'Z')
            app.One = uilabel(app.UIFigure);
            app.One.Text = 'Num trials:';
            app.FailsuntilrewardsLabel = uilabel(app.UIFigure);
            app.FailsuntilrewardsLabel.Text = 'Fails until reward';
            app.Fails2RewEditField = uieditfield(app.UIFigure, 'numeric');
            app.Fails2RewEditField.ValueChangedFcn = createCallbackFcn(app, @Fails2RewEditFieldValueChanged, true);
            app.SelectiontimemsLabel = uilabel(app.UIFigure);
            app.SelectiontimemsLabel.Text = 'Selection time (ms)';
            app.SelectTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectTimeEditField.ValueChangedFcn = createCallbackFcn(app, @SelectTimeEditFieldValueChanged, true);
            app.EndTrialButton = uibutton(app.UIFigure, 'state');
            app.EndTrialButton.Text = 'End of Trial';
            app.EndBlockButton = uibutton(app.UIFigure, 'state');
            app.EndBlockButton.Text = 'End of Block';
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.LWaterButton = uibutton(app.UIFigure, 'push');
            app.LWaterButton.ButtonPushedFcn = createCallbackFcn(app, @LWaterButtonPushed, true);
            app.LWaterButton.Text = 'Water';
            app.RWaterButton = uibutton(app.UIFigure, 'push');
            app.RWaterButton.ButtonPushedFcn = createCallbackFcn(app, @RWaterButtonPushed, true);
            app.RWaterButton.Text = 'Water';
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MinEditField_2Label = uilabel(app.UIFigure);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.TrialsperblockLabel = uilabel(app.UIFigure);
            app.TrialsperblockLabel.Text = 'Trials per block';
            app.LCueButton = uibutton(app.UIFigure, 'push');
            app.LCueButton.ButtonPushedFcn = createCallbackFcn(app, @LCueButtonPushed, true);
            app.LCueButton.Text = 'Cue';
            app.RCueButton = uibutton(app.UIFigure, 'push');
            app.RCueButton.ButtonPushedFcn = createCallbackFcn(app, @RCueButtonPushed, true);
            app.RCueButton.Text = 'Cue';
            app.ManualTriggeringLabel = uilabel(app.UIFigure);
            app.ManualTriggeringLabel.Text = 'Manual Triggering';
            app.WaterCueSwitchLabel = uilabel(app.UIFigure);
            app.WaterCueSwitchLabel.Text = 'Water + Cue';
            app.WaterCueSwitch = uiswitch(app.UIFigure, 'slider');
            app.WaterCueSwitch.Items = {'off', 'on'};
            app.WaterCueSwitch.Value = 'off';
            app.LeftLabel = uilabel(app.UIFigure);
            app.LeftLabel.Text = 'Left';
            app.RightLabel = uilabel(app.UIFigure);
            app.RightLabel.Text = 'Right';
            app.EventLabel_2 = uilabel(app.UIFigure);
            app.EventLabel_2.Text = 'Event:';
            app.EventLabel = uilabel(app.UIFigure);
            app.EventLabel.Text = 'Set Parameters';
            app.StartExperimentButton = uibutton(app.UIFigure, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.StopExperimentButton = uibutton(app.UIFigure, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.RResetButton = uibutton(app.UIFigure, 'push');
            app.RResetButton.ButtonPushedFcn = createCallbackFcn(app, @RResetButtonPushed, true);
            app.RResetButton.Text = 'Reset';
            app.LResetButton = uibutton(app.UIFigure, 'push');
            app.LResetButton.ButtonPushedFcn = createCallbackFcn(app, @LResetButtonPushed, true);
            app.LResetButton.Text = 'Reset';
            app.Four = uilabel(app.UIFigure);
            app.Four.Text = 'Num rewards:';
            app.Three = uilabel(app.UIFigure);
            app.Three.Text = 'Num blocks:';
            app.Two = uilabel(app.UIFigure);
            app.Two.Text = 'Reward in block:';
            app.Five = uilabel(app.UIFigure);
            app.Five.Text = '/';
            app.LLCountLabel = uilabel(app.UIFigure);
            app.LLCountLabel.Text = '0';
            app.RLCountLabel = uilabel(app.UIFigure);
            app.RLCountLabel.Text = '0';
            app.NumTrialsLabel = uilabel(app.UIFigure);
            app.NumTrialsLabel.Text = '0';
            app.RewBlockLabel = uilabel(app.UIFigure);
            app.RewBlockLabel.Text = '0';
            app.NumBlockLabel = uilabel(app.UIFigure);
            app.NumBlockLabel.Text = '0';
            app.TotRewLabel = uilabel(app.UIFigure);
            app.TotRewLabel.Text = '0';
            app.OutOfLabel = uilabel(app.UIFigure);
            app.OutOfLabel.Text = '0';
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = '0';
            app.BlockEventLabel = uilabel(app.UIFigure);
            app.BlockEventLabel.Text = 'Block';
            app.BlockLabel = uilabel(app.UIFigure);
            app.BlockLabel.Text = 'Idle';
            app.IdleLeft = uilabel(app.UIFigure);
            app.IdleLeft.Text = '0';
            app.IdleRight = uilabel(app.UIFigure);
            app.IdleRight.Text = '0';
            app.LeftLicksLabel = uilabel(app.UIFigure);
            app.LeftLicksLabel.Text = 'Left Licks';
            app.RightLicksLabel = uilabel(app.UIFigure);
            app.RightLicksLabel.Text = 'Right Licks';
            app.Six = uilabel(app.UIFigure);
            app.Six.Text = 'Free rewards:';
            app.FreeRewLabel = uilabel(app.UIFigure);
            app.FreeRewLabel.Text = '0';
            app.Seven = uilabel(app.UIFigure);
            app.Seven.Text = 'Trial in block:';
            app.TrialInBlockLabel = uilabel(app.UIFigure);
            app.TrialInBlockLabel.Text = '0';
            app.TimeoutmLabel = uilabel(app.UIFigure);
            app.TimeoutmLabel.Text = 'Timeout (m)';
            app.TimeoutEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeoutEditField.ValueChangedFcn = createCallbackFcn(app, @TimeoutEditFieldValueChanged, true);
            app.LeftLabel_2 = uilabel(app.UIFigure);
            app.LeftLabel_2.Text = 'Left';
            app.RightLabel_2 = uilabel(app.UIFigure);
            app.RightLabel_2.Text = 'Right';
            app.MaxOmissionsLabel = uilabel(app.UIFigure);
            app.MaxOmissionsLabel.Text = 'Omissions';
            app.OmissionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.OmissionsEditField.ValueChangedFcn = createCallbackFcn(app, @OmissionsEditFieldValueChanged, true);
            app.ResaveButton = uibutton(app.UIFigure, 'push');
            app.ResaveButton.ButtonPushedFcn = createCallbackFcn(app, @ResaveButtonPushed, true);
            app.ResaveButton.Text = 'Re-save';
            app.ExperimentalDataLabel = uilabel(app.UIFigure);
            app.ExperimentalDataLabel.Text = 'Experimental Data';
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.ENLdurationmsLabel = uilabel(app.UIFigure);
            app.ENLdurationmsLabel.Text = 'ENL duration (ms)';
            app.MaxEditField_2Label = uilabel(app.UIFigure);
            app.MaxEditField_2Label.Text = 'Max';
            app.ENLMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ENLMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ENLMaxEditFieldValueChanged, true);
            app.MinEditField_2Label_2 = uilabel(app.UIFigure);
            app.MinEditField_2Label_2.Text = 'Min';
            app.ENLMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ENLMinEditField.ValueChangedFcn = createCallbackFcn(app, @ENLMinEditFieldValueChanged, true);
            app.MaxEditField_2Label_2 = uilabel(app.UIFigure);
            app.MaxEditField_2Label_2.Text = 'Step';
            app.ENLStepEditField = uieditfield(app.UIFigure, 'numeric');
            app.ENLStepEditField.ValueChangedFcn = createCallbackFcn(app, @ENLStepEditFieldValueChanged, true);
            app.LeftrewardsizeLabel = uilabel(app.UIFigure);
            app.LeftrewardsizeLabel.Text = 'Left reward size';
            app.LeftMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftMinEditField.ValueChangedFcn = createCallbackFcn(app, @LeftMinEditFieldValueChanged, true);
            app.MaxEditField_2Label_3 = uilabel(app.UIFigure);
            app.MaxEditField_2Label_3.Text = 'Max';
            app.LeftMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftMaxEditField.ValueChangedFcn = createCallbackFcn(app, @LeftMaxEditFieldValueChanged, true);
            app.MinEditField_2Label_3 = uilabel(app.UIFigure);
            app.MinEditField_2Label_3.Text = 'Min';
            app.StepEditField_2Label = uilabel(app.UIFigure);
            app.StepEditField_2Label.Text = 'Step';
            app.LeftStepEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftStepEditField.ValueChangedFcn = createCallbackFcn(app, @LeftStepEditFieldValueChanged, true);
            app.RightrewardsizeLabel = uilabel(app.UIFigure);
            app.RightrewardsizeLabel.Text = 'Right reward size';
            app.RightMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightMinEditField.ValueChangedFcn = createCallbackFcn(app, @RightMinEditFieldValueChanged, true);
            app.MaxEditField_2Label_4 = uilabel(app.UIFigure);
            app.MaxEditField_2Label_4.Text = 'Max';
            app.RightMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightMaxEditField.ValueChangedFcn = createCallbackFcn(app, @RightMaxEditFieldValueChanged, true);
            app.MinEditField_2Label_4 = uilabel(app.UIFigure);
            app.MinEditField_2Label_4.Text = 'Min';
            app.StepEditField_2Label_2 = uilabel(app.UIFigure);
            app.StepEditField_2Label_2.Text = 'Step';
            app.RightStepEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightStepEditField.ValueChangedFcn = createCallbackFcn(app, @RightStepEditFieldValueChanged, true);
            app.LCalibrateButton = uibutton(app.UIFigure, 'state');
            app.LCalibrateButton.ValueChangedFcn = createCallbackFcn(app, @LCalibrateButtonValueChanged, true);
            app.LCalibrateButton.Text = 'Calibrate';
            app.RCalibrateButton = uibutton(app.UIFigure, 'state');
            app.RCalibrateButton.ValueChangedFcn = createCallbackFcn(app, @RCalibrateButtonValueChanged, true);
            app.RCalibrateButton.Text = 'Calibrate';
            app.RewardProbabilitySpinnerLabel = uilabel(app.UIFigure);
            app.RewardProbabilitySpinnerLabel.Text = 'Reward Probability';
            app.RewardProbabilitySpinner = uispinner(app.UIFigure);
            app.RewardProbabilitySpinner.Step = 5;
            app.RewardProbabilitySpinner.Limits = [0 100];
            app.RewardProbabilitySpinner.ValueChangedFcn = createCallbackFcn(app, @RewardProbabilitySpinnerValueChanged, true);
            app.SpoutStart = uidropdown(app.UIFigure);
            app.SpoutStart.Items = {'Random', 'Left', 'Right'};
            app.SpoutStart.ValueChangedFcn = createCallbackFcn(app, @SpoutStartValueChanged, true);
            app.SpoutStart.Value = 'Random';
            app.CuePenaltymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.CuePenaltymsEditField.ValueChangedFcn = createCallbackFcn(app, @CuePenaltymsEditFieldValueChanged, true);
            app.LeftCuefreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftCuefreqEditField.ValueChangedFcn = createCallbackFcn(app, @LeftCuefreqEditFieldValueChanged, true);
            app.RightCuefreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightCuefreqEditField.ValueChangedFcn = createCallbackFcn(app, @RightCuefreqEditFieldValueChanged, true);
            app.CueFreqhzLabel = uilabel(app.UIFigure);
            app.CueFreqhzLabel.Text = 'Cue Freq (hz)';
            app.HowtoOptoStimulatePanel = uipanel(app.UIFigure);
            app.HowtoOptoStimulatePanel.Title = 'How to Opto Stimulate';
            app.CorrecttrialsbeforeoptoEditFieldLabel = uilabel(app.HowtoOptoStimulatePanel);
            app.CorrecttrialsbeforeoptoEditFieldLabel.Text = 'Correct trials before opto';
            app.CorrecttrialsbeforeoptoEditField = uieditfield(app.HowtoOptoStimulatePanel, 'numeric');
            app.CorrecttrialsbeforeoptoEditField.ValueChangedFcn = createCallbackFcn(app, @CorrecttrialsbeforeoptoEditFieldValueChanged, true);
            app.OptoDelaymsEditFieldLabel = uilabel(app.HowtoOptoStimulatePanel);
            app.OptoDelaymsEditFieldLabel.Text = 'Opto Delay (ms)';
            app.OptoDelaymsEditField = uieditfield(app.HowtoOptoStimulatePanel, 'numeric');
            app.OptoDelaymsEditField.ValueChangedFcn = createCallbackFcn(app, @OptoDelaymsEditFieldValueChanged, true);
            app.OptoProbabilitySpinnerLabel = uilabel(app.HowtoOptoStimulatePanel);
            app.OptoProbabilitySpinnerLabel.Text = 'Opto Probability';
            app.OptoProbabilitySpinner = uispinner(app.HowtoOptoStimulatePanel);
            app.OptoProbabilitySpinner.Step = 5;
            app.OptoProbabilitySpinner.Limits = [0 100];
            app.OptoProbabilitySpinner.ValueChangedFcn = createCallbackFcn(app, @OptoProbabilitySpinnerValueChanged, true);
            app.SpoutStartDropDownLabel = uilabel(app.UIFigure);
            app.SpoutStartDropDownLabel.Text = 'Spout Start';
            app.WhentoOptoStimulatePanel = uipanel(app.UIFigure);
            app.WhentoOptoStimulatePanel.Title = 'When to Opto Stimulate';
            app.OptoDuringLabel = uilabel(app.WhentoOptoStimulatePanel);
            app.OptoDuringLabel.Text = 'Opto During:';
            app.OptoLabel = uilabel(app.WhentoOptoStimulatePanel);
            app.OptoLabel.Text = 'Opto Stimulation';
            app.OptoStimulationSwitch = uiswitch(app.WhentoOptoStimulatePanel, 'slider');
            app.OptoStimulationSwitch.Items = {'off', 'on'};
            app.OptoStimulationSwitch.ValueChangedFcn = createCallbackFcn(app, @OptoStimulationSwitchValueChanged, true);
            app.OptoStimulationSwitch.Value = 'off';
            app.BlockSwitchCheckBox = uicheckbox(app.WhentoOptoStimulatePanel);
            app.BlockSwitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlockSwitchCheckBoxValueChanged, true);
            app.BlockSwitchCheckBox.Text = '   Block Switch';
            app.ENLCheckBox = uicheckbox(app.WhentoOptoStimulatePanel);
            app.ENLCheckBox.ValueChangedFcn = createCallbackFcn(app, @ENLCheckBoxValueChanged, true);
            app.ENLCheckBox.Text = '   ENL';
            app.CueCheckBox = uicheckbox(app.WhentoOptoStimulatePanel);
            app.CueCheckBox.ValueChangedFcn = createCallbackFcn(app, @CueCheckBoxValueChanged, true);
            app.CueCheckBox.Text = '   Cue';
            app.ConsumptionCheckBox = uicheckbox(app.WhentoOptoStimulatePanel);
            app.ConsumptionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsumptionCheckBoxValueChanged, true);
            app.ConsumptionCheckBox.Text = '   Consumption';
            app.CuePenaltyLabel = uilabel(app.UIFigure);
            app.CuePenaltyLabel.Text = 'Cue Penalty (ms)';
            app.MultiStimParametersPanel = uipanel(app.UIFigure);
            app.MultiStimParametersPanel.Title = 'Multi-Stim Parameters';
            app.AllowmultistiminENLpenaltyCheckBox = uicheckbox(app.MultiStimParametersPanel);
            app.AllowmultistiminENLpenaltyCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllowmultistiminENLpenaltyCheckBoxValueChanged, true);
            app.AllowmultistiminENLpenaltyCheckBox.Text = '   Allow multi-stim in ENL penalty';
            app.AllowmultistiminblockCheckBox = uicheckbox(app.MultiStimParametersPanel);
            app.AllowmultistiminblockCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllowmultistiminblockCheckBoxValueChanged, true);
            app.AllowmultistiminblockCheckBox.Text = '   Allow multi-stim in block';
            app.PulseModulationParametersPanel = uipanel(app.UIFigure);
            app.PulseModulationParametersPanel.Title = 'Pulse Modulation Parameters';
            app.ContinuousCheckBox = uicheckbox(app.PulseModulationParametersPanel);
            app.ContinuousCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContinuousCheckBoxValueChanged, true);
            app.ContinuousCheckBox.Text = '   Continuous';
            app.PulseDurationmsEditFieldLabel = uilabel(app.PulseModulationParametersPanel);
            app.PulseDurationmsEditFieldLabel.Text = 'Pulse Duration (ms)';
            app.PulseDurationmsEditField = uieditfield(app.PulseModulationParametersPanel, 'numeric');
            app.PulseDurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @PulseDurationmsEditFieldValueChanged, true);
            app.OptoFrequencyHzEditFieldLabel = uilabel(app.PulseModulationParametersPanel);
            app.OptoFrequencyHzEditFieldLabel.Text = 'Opto Frequency (Hz)';
            app.OptoFrequencyHzEditField = uieditfield(app.PulseModulationParametersPanel, 'numeric');
            app.OptoFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @OptoFrequencyHzEditFieldValueChanged, true);
            app.OptoDurationmsEditFieldLabel = uilabel(app.PulseModulationParametersPanel);
            app.OptoDurationmsEditFieldLabel.Text = 'Opto Duration (ms)';
            app.OptoDurationmsEditField = uieditfield(app.PulseModulationParametersPanel, 'numeric');
            app.OptoDurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @OptoDurationmsEditFieldValueChanged, true);
            app.RunTimeLabel = uilabel(app.UIFigure);
            app.RunTimeLabel.Text = 'Run Time:';
            app.LeftSpinnerLabel = uilabel(app.UIFigure);
            app.LeftSpinnerLabel.Text = 'Left';
            app.LCalibrateEditField = uispinner(app.UIFigure);
            app.LCalibrateEditField.ValueChangedFcn = createCallbackFcn(app, @LCalibrateEditFieldValueChanged, true);
            app.RightSpinnerLabel = uilabel(app.UIFigure);
            app.RightSpinnerLabel.Text = 'Right';
            app.RCalibrateEditField = uispinner(app.UIFigure);
            app.RCalibrateEditField.ValueChangedFcn = createCallbackFcn(app, @RCalibrateEditFieldValueChanged, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.WillstopexperimentbutwillnotsavedataMenu = uimenu(app.ContextMenu);
            app.WillstopexperimentbutwillnotsavedataMenu.Text = 'Will stop experiment, but will not save data';
            % Assign app.ContextMenu
            app.StopExperimentButton.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.WillstopexperimentattheendofthecurrentblockMenu = uimenu(app.ContextMenu2);
            app.WillstopexperimentattheendofthecurrentblockMenu.Text = 'Will stop experiment at the end of the current block';
            % Assign app.ContextMenu2
            app.EndBlockButton.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.WillstopexperimentattheendofthecurrenttrialMenu = uimenu(app.ContextMenu3);
            app.WillstopexperimentattheendofthecurrenttrialMenu.Text = 'Will stop experiment at the end of the current trial';
            % Assign app.ContextMenu3
            app.EndTrialButton.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.UIFigure);
            app.StartexperimentMenu = uimenu(app.ContextMenu4);
            app.StartexperimentMenu.Text = 'Start experiment';
            % Assign app.ContextMenu4
            app.StartExperimentButton.ContextMenu = app.ContextMenu4;
            app.ContextMenu5 = uicontextmenu(app.UIFigure);
            app.MinimumnumberoftrialsineachblockMenu = uimenu(app.ContextMenu5);
            app.MinimumnumberoftrialsineachblockMenu.Text = 'Minimum number of trials in each block';
            % Assign app.ContextMenu5
            app.MinEditField.ContextMenu = app.ContextMenu5;
            app.ContextMenu6 = uicontextmenu(app.UIFigure);
            app.MaximumnumberoftrialsperblockMenu = uimenu(app.ContextMenu6);
            app.MaximumnumberoftrialsperblockMenu.Text = 'Maximum number of trials per block';
            % Assign app.ContextMenu6
            app.MaxEditField.ContextMenu = app.ContextMenu6;
            app.ContextMenu7 = uicontextmenu(app.UIFigure);
            app.Menu2 = uimenu(app.ContextMenu7);
            app.Menu2.Text = 'Number of consecutive incorrect trials and/or omissions before a reward from the correct spout is given';
            % Assign app.ContextMenu7
            app.Fails2RewEditField.ContextMenu = app.ContextMenu7;
            app.ContextMenu8 = uicontextmenu(app.UIFigure);
            app.AmountoftimeanimalhastomakeaselectionbeforeanomissionMenu = uimenu(app.ContextMenu8);
            app.AmountoftimeanimalhastomakeaselectionbeforeanomissionMenu.Text = 'Amount of time animal has to make a selection before an omission';
            % Assign app.ContextMenu8
            app.SelectTimeEditField.ContextMenu = app.ContextMenu8;
            app.ContextMenu9 = uicontextmenu(app.UIFigure);
            app.Menu2_2 = uimenu(app.ContextMenu9);
            app.Menu2_2.Text = 'Amount of time from cue that cue penalty period lasts. If animal licks during cue penalty period, return to ENL period';
            % Assign app.ContextMenu9
            app.CuePenaltymsEditField.ContextMenu = app.ContextMenu9;
            app.ContextMenu10 = uicontextmenu(app.UIFigure);
            app.MinimumENLdurationMenu = uimenu(app.ContextMenu10);
            app.MinimumENLdurationMenu.Text = 'Minimum ENL duration';
            % Assign app.ContextMenu10
            app.ENLMinEditField.ContextMenu = app.ContextMenu10;
            app.ContextMenu11 = uicontextmenu(app.UIFigure);
            app.MaximumENLdurationMenu = uimenu(app.ContextMenu11);
            app.MaximumENLdurationMenu.Text = 'Maximum ENL duration';
            % Assign app.ContextMenu11
            app.ENLMaxEditField.ContextMenu = app.ContextMenu11;
            app.ContextMenu12 = uicontextmenu(app.UIFigure);
            app.MinimumleftrewardsizedurationsolenoidsareopenforMenu = uimenu(app.ContextMenu12);
            app.MinimumleftrewardsizedurationsolenoidsareopenforMenu.Text = 'Minimum left reward size (duration solenoids are open for)';
            % Assign app.ContextMenu12
            app.LeftMinEditField.ContextMenu = app.ContextMenu12;
            app.ContextMenu13 = uicontextmenu(app.UIFigure);
            app.MaximumleftrewardsizedurationsolenoidsareopenforMenu = uimenu(app.ContextMenu13);
            app.MaximumleftrewardsizedurationsolenoidsareopenforMenu.Text = 'Maximum left reward size (duration solenoids are open for)';
            % Assign app.ContextMenu13
            app.LeftMaxEditField.ContextMenu = app.ContextMenu13;
            app.ContextMenu14 = uicontextmenu(app.UIFigure);
            app.MinimumrightrewardsizedurationsolenoidsareopenforMenu = uimenu(app.ContextMenu14);
            app.MinimumrightrewardsizedurationsolenoidsareopenforMenu.Text = 'Minimum right reward size (duration solenoids are open for)';
            % Assign app.ContextMenu14
            app.RightMinEditField.ContextMenu = app.ContextMenu14;
            app.ContextMenu15 = uicontextmenu(app.UIFigure);
            app.MaximumrightrewardsizedurationsolenoidsareopenforMenu = uimenu(app.ContextMenu15);
            app.MaximumrightrewardsizedurationsolenoidsareopenforMenu.Text = 'Maximum right reward size (duration solenoids are open for)';
            % Assign app.ContextMenu15
            app.RightMaxEditField.ContextMenu = app.ContextMenu15;
            app.ContextMenu16 = uicontextmenu(app.UIFigure);
            app.ProbabilitythatarewardisgivenintheeventofacorrectlickMenu = uimenu(app.ContextMenu16);
            app.ProbabilitythatarewardisgivenintheeventofacorrectlickMenu.Text = 'Probability that a reward is given in the event of a correct lick';
            % Assign app.ContextMenu16
            app.RewardProbabilitySpinner.ContextMenu = app.ContextMenu16;
            app.ContextMenu17 = uicontextmenu(app.UIFigure);
            app.WhichblocktheexperimentbeginsonMenu = uimenu(app.ContextMenu17);
            app.WhichblocktheexperimentbeginsonMenu.Text = 'Which block the experiment begins on';
            % Assign app.ContextMenu17
            app.SpoutStart.ContextMenu = app.ContextMenu17;
            app.ContextMenu18 = uicontextmenu(app.UIFigure);
            app.NameoftheanimalUsedtonamethebehavioraldatafilesMenu = uimenu(app.ContextMenu18);
            app.NameoftheanimalUsedtonamethebehavioraldatafilesMenu.Text = 'Name of the animal. Used to name the behavioral data files';
            % Assign app.ContextMenu18
            app.NameEditField.ContextMenu = app.ContextMenu18;
            app.ContextMenu19 = uicontextmenu(app.UIFigure);
            app.ExperimentrunningtimerMenu = uimenu(app.ContextMenu19);
            app.ExperimentrunningtimerMenu.Text = 'Experiment running timer';
            % Assign app.ContextMenu19
            app.TimeLabel.ContextMenu = app.ContextMenu19;
            app.ContextMenu20 = uicontextmenu(app.UIFigure);
            app.ResavebehavioraldatacollectedMenu = uimenu(app.ContextMenu20);
            app.ResavebehavioraldatacollectedMenu.Text = 'Re-save behavioral data collected';
            % Assign app.ContextMenu20
            app.ResaveButton.ContextMenu = app.ContextMenu20;
            app.ContextMenu21 = uicontextmenu(app.UIFigure);
            app.Menu2_3 = uimenu(app.ContextMenu21);
            app.Menu2_3.Text = 'Adjust parameters as needed upon app startup. Must push before experiment is allowed to begin. Used to test spout connection';
            app.ContextMenu22 = uicontextmenu(app.UIFigure);
            app.Menu2_4 = uimenu(app.ContextMenu22);
            app.Menu2_4.Text = 'Maximum number of consecutive omissions to cause experiment to end. Default value is 20';
            % Assign app.ContextMenu22
            app.OmissionsEditField.ContextMenu = app.ContextMenu22;
            app.ContextMenu23 = uicontextmenu(app.UIFigure);
            app.Menu2_5 = uimenu(app.ContextMenu23);
            app.Menu2_5.Text = 'Maximum number of minutes elapsed to cause experiment to end. Default value is 60';
            % Assign app.ContextMenu23
            app.TimeoutEditField.ContextMenu = app.ContextMenu23;
            app.ContextMenu24 = uicontextmenu(app.UIFigure);
            app.WhateventtheexperimentiscurrentlyonMenu = uimenu(app.ContextMenu24);
            app.WhateventtheexperimentiscurrentlyonMenu.Text = 'What event the experiment is currently on';
            % Assign app.ContextMenu24
            app.EventLabel.ContextMenu = app.ContextMenu24;
            app.ContextMenu25 = uicontextmenu(app.UIFigure);
            app.WhichblocksidetheexperimentiscurrentlyonMenu = uimenu(app.ContextMenu25);
            app.WhichblocksidetheexperimentiscurrentlyonMenu.Text = 'Which block side the experiment is currently on';
            % Assign app.ContextMenu25
            app.BlockLabel.ContextMenu = app.ContextMenu25;
            app.ContextMenu26 = uicontextmenu(app.UIFigure);
            app.FrequencyofleftcueMenu = uimenu(app.ContextMenu26);
            app.FrequencyofleftcueMenu.Text = 'Frequency of left cue';
            % Assign app.ContextMenu26
            app.LeftCuefreqEditField.ContextMenu = app.ContextMenu26;
            app.ContextMenu27 = uicontextmenu(app.UIFigure);
            app.FrequencyofrightcueMenu = uimenu(app.ContextMenu27);
            app.FrequencyofrightcueMenu.Text = 'Frequency of right cue';
            % Assign app.ContextMenu27
            app.RightCuefreqEditField.ContextMenu = app.ContextMenu27;
            app.ContextMenu28 = uicontextmenu(app.UIFigure);
            app.ManualleftcueMenu = uimenu(app.ContextMenu28);
            app.ManualleftcueMenu.Text = 'Manual left cue';
            % Assign app.ContextMenu28
            app.LCueButton.ContextMenu = app.ContextMenu28;
            app.ContextMenu29 = uicontextmenu(app.UIFigure);
            app.ManualrightcueMenu = uimenu(app.ContextMenu29);
            app.ManualrightcueMenu.Text = 'Manual right cue';
            % Assign app.ContextMenu29
            app.RCueButton.ContextMenu = app.ContextMenu29;
            app.ContextMenu30 = uicontextmenu(app.UIFigure);
            app.ManualleftrewardMenu = uimenu(app.ContextMenu30);
            app.ManualleftrewardMenu.Text = 'Manual left reward';
            % Assign app.ContextMenu30
            app.LWaterButton.ContextMenu = app.ContextMenu30;
            app.ContextMenu31 = uicontextmenu(app.UIFigure);
            app.ManualrightrewardMenu = uimenu(app.ContextMenu31);
            app.ManualrightrewardMenu.Text = 'Manual right reward';
            % Assign app.ContextMenu31
            app.RWaterButton.ContextMenu = app.ContextMenu31;
            app.ContextMenu32 = uicontextmenu(app.UIFigure);
            app.WhenoffwaterandcuewilloperateindependentlyMenu = uimenu(app.ContextMenu32);
            app.WhenoffwaterandcuewilloperateindependentlyMenu.Text = 'When off, water and cue will operate independently';
            app.Menu = uimenu(app.ContextMenu32);
            app.Menu.Text = 'When on, if water button is pushed, cue will also execute. Cue button still functions independently';
            % Assign app.ContextMenu32
            app.WaterCueSwitch.ContextMenu = app.ContextMenu32;
            app.ContextMenu33 = uicontextmenu(app.UIFigure);
            app.Resetnumberofmanualleftrewardsto0Menu = uimenu(app.ContextMenu33);
            app.Resetnumberofmanualleftrewardsto0Menu.Text = 'Reset number of manual left rewards to 0';
            % Assign app.ContextMenu33
            app.LResetButton.ContextMenu = app.ContextMenu33;
            app.ContextMenu34 = uicontextmenu(app.UIFigure);
            app.Resetnumberofmanualrightrewardsto0Menu = uimenu(app.ContextMenu34);
            app.Resetnumberofmanualrightrewardsto0Menu.Text = 'Reset number of manual right rewards to 0';
            % Assign app.ContextMenu34
            app.RResetButton.ContextMenu = app.ContextMenu34;
            app.ContextMenu35 = uicontextmenu(app.UIFigure);
            app.NumberofmanualleftrewardsMenu = uimenu(app.ContextMenu35);
            app.NumberofmanualleftrewardsMenu.Text = 'Number of manual left rewards';
            % Assign app.ContextMenu35
            app.LLCountLabel.ContextMenu = app.ContextMenu35;
            app.ContextMenu36 = uicontextmenu(app.UIFigure);
            app.Resetnumberofmanualrightrewardsto0Menu_2 = uimenu(app.ContextMenu36);
            app.Resetnumberofmanualrightrewardsto0Menu_2.Text = 'Reset number of manual right rewards to 0';
            app.ContextMenu37 = uicontextmenu(app.UIFigure);
            app.Willcause100leftrewardsUsedtocalibrateleftrewardsizeMenu = uimenu(app.ContextMenu37);
            app.Willcause100leftrewardsUsedtocalibrateleftrewardsizeMenu.Text = 'Will cause 100 left rewards. Used to calibrate left reward size';
            % Assign app.ContextMenu37
            app.LCalibrateButton.ContextMenu = app.ContextMenu37;
            app.ContextMenu38 = uicontextmenu(app.UIFigure);
            app.Willcause100rightrewardsUsedtocalibraterightrewardsizeMenu = uimenu(app.ContextMenu38);
            app.Willcause100rightrewardsUsedtocalibraterightrewardsizeMenu.Text = 'Will cause 100 right rewards. Used to calibrate right reward size';
            % Assign app.ContextMenu38
            app.RCalibrateButton.ContextMenu = app.ContextMenu38;
            app.ContextMenu39 = uicontextmenu(app.UIFigure);
            app.NumberofmanualrightrewardsMenu = uimenu(app.ContextMenu39);
            app.NumberofmanualrightrewardsMenu.Text = 'Number of manual right rewards';
            app.ContextMenu40 = uicontextmenu(app.UIFigure);
            app.Menu2_6 = uimenu(app.ContextMenu40);
            app.Menu2_6.Text = 'Optogenetic stimulation on/off. Other opto parameters do not function if opto is off';
            % Assign app.ContextMenu40
            app.OptoStimulationSwitch.ContextMenu = app.ContextMenu40;
            app.ContextMenu41 = uicontextmenu(app.UIFigure);
            app.ProbabilitythatoptostimulationwilloccurMenu = uimenu(app.ContextMenu41);
            app.ProbabilitythatoptostimulationwilloccurMenu.Text = 'Probability that opto stimulation will occur';
            % Assign app.ContextMenu41
            app.OptoProbabilitySpinner.ContextMenu = app.ContextMenu41;
            app.ContextMenu42 = uicontextmenu(app.UIFigure);
            app.NumberofcorrecttrialsnecessarybeforeoptobeginsMenu = uimenu(app.ContextMenu42);
            app.NumberofcorrecttrialsnecessarybeforeoptobeginsMenu.Text = 'Number of correct trials necessary before opto begins';
            % Assign app.ContextMenu42
            app.CorrecttrialsbeforeoptoEditField.ContextMenu = app.ContextMenu42;
            app.ContextMenu43 = uicontextmenu(app.UIFigure);
            app.TimebetweenoptostarttimeandwhenthelaserisactuallyonMenu = uimenu(app.ContextMenu43);
            app.TimebetweenoptostarttimeandwhenthelaserisactuallyonMenu.Text = 'Time between opto start time and when the laser is actually on';
            % Assign app.ContextMenu43
            app.OptoDelaymsEditField.ContextMenu = app.ContextMenu43;
            app.ContextMenu44 = uicontextmenu(app.UIFigure);
            app.IfoptoactiveduringENLallowoptostimeverytimeENLisresetMenu = uimenu(app.ContextMenu44);
            app.IfoptoactiveduringENLallowoptostimeverytimeENLisresetMenu.Text = 'If opto active during ENL, allow opto-stim every time ENL re-starts (in the case of an ENL penalty)';
            % Assign app.ContextMenu44
            app.AllowmultistiminENLpenaltyCheckBox.ContextMenu = app.ContextMenu44;
            app.ContextMenu45 = uicontextmenu(app.UIFigure);
            app.AllowmultipleoptostimulationsinablockMenu = uimenu(app.ContextMenu45);
            app.AllowmultipleoptostimulationsinablockMenu.Text = 'Allow multiple opto stimulations in a block';
            % Assign app.ContextMenu45
            app.AllowmultistiminblockCheckBox.ContextMenu = app.ContextMenu45;
            app.ContextMenu46 = uicontextmenu(app.UIFigure);
            app.AllowoptogeneticstimulationtohavecontinuouslaserstimulationMenu = uimenu(app.ContextMenu46);
            app.AllowoptogeneticstimulationtohavecontinuouslaserstimulationMenu.Text = 'Allow optogenetic stimulation to have continuous laser stimulation';
            % Assign app.ContextMenu46
            app.ContinuousCheckBox.ContextMenu = app.ContextMenu46;
            app.ContextMenu47 = uicontextmenu(app.UIFigure);
            app.DurationthatlaserisonforMenu = uimenu(app.ContextMenu47);
            app.DurationthatlaserisonforMenu.Text = 'Duration that laser is on for';
            % Assign app.ContextMenu47
            app.OptoDurationmsEditField.ContextMenu = app.ContextMenu47;
            app.ContextMenu48 = uicontextmenu(app.UIFigure);
            app.IfnoncontinuousthedurationofeachlaserpulseMenu = uimenu(app.ContextMenu48);
            app.IfnoncontinuousthedurationofeachlaserpulseMenu.Text = 'If non-continuous, the duration of each laser pulse';
            % Assign app.ContextMenu48
            app.PulseDurationmsEditField.ContextMenu = app.ContextMenu48;
            app.ContextMenu49 = uicontextmenu(app.UIFigure);
            app.IfnoncontinuousfrequencyoflaserMenu = uimenu(app.ContextMenu49);
            app.IfnoncontinuousfrequencyoflaserMenu.Text = 'If non-continuous, frequency of laser';
            % Assign app.ContextMenu49
            app.OptoFrequencyHzEditField.ContextMenu = app.ContextMenu49;
            app.ContextMenu50 = uicontextmenu(app.UIFigure);
            app.NumberofmanualrightrewardsMenu_2 = uimenu(app.ContextMenu50);
            app.NumberofmanualrightrewardsMenu_2.Text = 'Number of manual right rewards';
            % Assign app.ContextMenu50
            app.RLCountLabel.ContextMenu = app.ContextMenu50;
            app.ContextMenu51 = uicontextmenu(app.UIFigure);
            app.LeftrewardsizedurationsolenoidsareopenforMenu = uimenu(app.ContextMenu51);
            app.LeftrewardsizedurationsolenoidsareopenforMenu.Text = 'Left reward size (duration solenoids are open for)';
            app.ContextMenu52 = uicontextmenu(app.UIFigure);
            app.RightrewardsizedurationsolenoidsareopenforMenu = uimenu(app.ContextMenu52);
            app.RightrewardsizedurationsolenoidsareopenforMenu.Text = 'Right reward size (duration solenoids are open for)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DualLickGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end